classdef app_designer_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadcellgEditField             matlab.ui.control.NumericEditField
        LoadcellgEditFieldLabel        matlab.ui.control.Label
        TareLoadCell                   matlab.ui.control.Button
        CalIMU                         matlab.ui.control.Button
        ControlLabel                   matlab.ui.control.Label
        PIDTunningToolLabel            matlab.ui.control.Label
        Min_signaldecr                 matlab.ui.control.Button
        Min_signalincr                 matlab.ui.control.Button
        Overlapdecr                    matlab.ui.control.Button
        Overlapincr                    matlab.ui.control.Button
        Ddecr                          matlab.ui.control.Button
        Dincr                          matlab.ui.control.Button
        Idecr                          matlab.ui.control.Button
        Iincr                          matlab.ui.control.Button
        StartStopmotorsButton          matlab.ui.control.Button
        Pdecr                          matlab.ui.control.Button
        Pincr                          matlab.ui.control.Button
        ConsoleTextArea                matlab.ui.control.TextArea
        ConsoleTextAreaLabel           matlab.ui.control.Label
        Rollangle_zdegEditField        matlab.ui.control.NumericEditField
        Rollangle_zdegEditFieldLabel   matlab.ui.control.Label
        Pitchangle_ydegEditField       matlab.ui.control.NumericEditField
        Pitchangle_ydegEditFieldLabel  matlab.ui.control.Label
        Yawangle_xdegEditField         matlab.ui.control.NumericEditField
        Yawangle_xdegEditFieldLabel    matlab.ui.control.Label
        UIAxesZAngle                   matlab.ui.control.UIAxes
        UIAxesSignal                   matlab.ui.control.UIAxes
        UIAxes3D                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        userInput = ''% Description
    end
    
    methods (Access = public)
        
        function keyPressCallback(app, event)
            % Use the global variable 'userInput' inside the callback function
            % Get the pressed key
            key = event.Key;
        
            % Handle the user input
            if ischar(key) && length(key) == 1 && isletter(key) && ~strcmp(key, app.userInput)
                app.userInput = key;
                fprintf(key)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Intialization for 3D visualization
            O  = [0,0,0]';
            e1 = [0,0,1]';
            e2 = [1,0,0]';
            e3 = [0,1,0]';

            
            %% UI Axis config: Output signal
            grid(app.UIAxesSignal, 'on');
            hold(app.UIAxesSignal, 'on');
            h1 = animatedline(app.UIAxesSignal, 'Color','m','LineWidth',2, 'MaximumNumPoints',500);
            h1.DisplayName = 'Roll - Z Angle (deg)';
            h2 = animatedline(app.UIAxesSignal, 'Color','g','LineWidth',2, 'MaximumNumPoints',500);
            h2.DisplayName = 'Load cell (g)';
            %%screen_property = get(0,'screensize');
            %%set(gcf, "OuterPosition", [0, screen_property(4)/2, ...
                %%screen_property(3)/2, screen_property(4)/2])
            xlabel(app.UIAxesSignal, "Time (s)");
            yline(app.UIAxesSignal, 0, 'black', '0 line');
            legend(app.UIAxesSignal, 'Location', 'northeastoutside'); % Turn on legend

            hold(app.UIAxesSignal, 'off');

            
            %% UI Axes config: 3D visualization
            hold(app.UIAxes3D, 'on');

            p1 = plot3(app.UIAxes3D, [O(1), e1(1)], ...
                                [O(2), e1(2)], ...
                                [O(3), e1(3)], ...
                                'LineWidth',2,'Color','r');
            p2 = plot3(app.UIAxes3D, [O(1), e2(1)], ...
                                [O(2), e2(2)], ...
                                [O(3), e2(3)], ...
                                'LineWidth',2,'Color','g');
            p3 = plot3(app.UIAxes3D, [O(1), e3(1)], ...
                                [O(2), e3(2)], ...
                                [O(3), e3(3)], ...
                                'LineWidth',2,'Color','b');

            grid(app.UIAxes3D, 'on');
            view(app.UIAxes3D, -30, 30);
            axis(app.UIAxes3D, 'equal');
            xlabel(app.UIAxes3D, 'y','FontSize',16);
            ylabel(app.UIAxes3D, 'z','FontSize',16);
            zlabel(app.UIAxes3D, 'x','FontSize',16);
            xlim(app.UIAxes3D, [-1,1]);
            ylim(app.UIAxes3D, [-1,1]);
            zlim(app.UIAxes3D, [-1,1]);
            hold(app.UIAxes3D, 'off');


            %% UI Axes: Orientation of the inverted pendulum
            pZ = plot(app.UIAxesZAngle, [0, tand(0)], [0, 1], 'ro-', 'LineWidth', 2);
            axis(app.UIAxesZAngle, [-1, 1, 0, 1]);  % Set axis limits


            %% Start Serial reading
            s = serialport('COM3', 9600);
            configureTerminator(s, "CR/LF");
            clc; close all; flush(s);
            
            time_divisor = 1;

            while 1
                %% Write to serial
                if (~strcmp(app.userInput, ''))
                    disp(app.userInput);
                    writeline(s, app.userInput);
                end
                app.userInput = '';

                %read from serial communication
                %do NOT add a semicolon so we can see its outputs via the command window
                try
                    string = readline(s);
                catch ME
                    if contains(ME.message, 'timeout')
                    warning('Failed to read from serial port within 1 second.');
                    string = ''; % or handle as needed
                    else
                        rethrow(ME); % if it's another error, rethrow it
                    end
                end
            
                %scans the string for inputs: %f 
                %double %% means one % in the string
                if (size(string) == 0)
                    continue;
                end
                disp(string)
                data = sscanf(string, "time: %d\ndeg_kalman_y: %f\ndeg_kalman_z: %f\nleftOutput: %f\nrightOutput: %f\nP: %f\nI: %f\nD: %f\noverlap: %f\nstartSignal: %f\nloadCell: %f\n");
                %make sure the data is correct i.e. 4 outputs is extracted from string
                if (size(data) < 2) 
                    continue;
                end
                %assign the variables
                time = data(1);
            
                th1 = 0;        %% x
                th2 = data(2);  %% y
                th3 = data(3);  %% z
                loadcell = data(11);
                
                %% Update UI value
                app.Yawangle_xdegEditField.Value = th1;
                app.Pitchangle_ydegEditField.Value = th2;
                app.Rollangle_zdegEditField.Value = th3;
                app.ConsoleTextArea.Value = string;
                app.LoadcellgEditField.Value = loadcell;
                
                %% Calculate 3D visualization
                R1 = [  cosd(th1), -sind(th1), 0;
                    sind(th1),  cosd(th1), 0;
                    0, 0, 1];
                R2 = [  1, 0, 0;
                    0, cosd(th2), -sind(th2);
                    0, sind(th2),  cosd(th2)];
                R3 = [  cosd(th3), 0, sind(th3);
                    0, 1, 0;
                    -sind(th3), 0, cosd(th3)];
                e1_pr = R3*R2*R1*e1;
                e2_pr = R3*R2*R1*e2;
                e3_pr = R3*R2*R1*e3;

                %% Draw signal
                xlim(app.UIAxesSignal, [max(0, time/time_divisor - 10000/time_divisor), time/time_divisor + 10000/time_divisor]);
                ylim(app.UIAxesSignal, [min(-90, loadcell - 30), max(90, loadcell + 30)]);
                addpoints(h1, time/time_divisor, th3);
                addpoints(h2, time/time_divisor, loadcell);
                
                %% Draw 3D visualization
                set(p1, 'XData', [O(1), e1_pr(1)], ...
                    'YData', [O(2), e1_pr(2)], ...
                    'ZData', [O(3), e1_pr(3)])
                set(p2, 'XData', [O(1), e2_pr(1)], ...
                    'YData', [O(2), e2_pr(2)], ...
                    'ZData', [O(3), e2_pr(3)])
                set(p3, 'XData', [O(1), e3_pr(1)], ...
                    'YData', [O(2), e3_pr(2)], ...
                    'ZData', [O(3), e3_pr(3)])

                %% Draw Z angle
                set(pZ, 'XData', [0, tand(th3)]);
                %%drawnow;
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            keyPressCallback(app, event);
        end

        % Button pushed function: Pincr
        function PincrButtonPushed(app, event)
            app.userInput = 'g';
        end

        % Button pushed function: Pdecr
        function PdecrButtonPushed(app, event)
            app.userInput = 'v';
        end

        % Button pushed function: Iincr
        function IincrButtonPushed(app, event)
            app.userInput = 'h';
        end

        % Button pushed function: Idecr
        function IdecrButtonPushed(app, event)
            app.userInput = 'b';
        end

        % Button pushed function: Dincr
        function DincrButtonPushed(app, event)
            app.userInput = 'j';
        end

        % Button pushed function: Ddecr
        function DdecrButtonPushed(app, event)
            app.userInput = 'n';
        end

        % Button pushed function: Overlapincr
        function OverlapincrButtonPushed(app, event)
            app.userInput = 'f';
        end

        % Button pushed function: Overlapdecr
        function OverlapdecrButtonPushed(app, event)
            app.userInput = 'c';
        end

        % Button pushed function: Min_signalincr
        function Min_signalincrButtonPushed(app, event)
            app.userInput = 'd';
        end

        % Button pushed function: Min_signaldecr
        function Min_signaldecrButtonPushed(app, event)
            app.userInput = 'x';
        end

        % Button pushed function: StartStopmotorsButton
        function StartStopmotorsButtonPushed(app, event)
            app.userInput = 's';
        end

        % Button pushed function: CalIMU
        function CalIMUButtonPushed(app, event)
            app.userInput = 't';
        end

        % Button pushed function: TareLoadCell
        function TareLoadCellButtonPushed(app, event)
            app.userInput = 'l';
        end

        % Callback function
        function CalLeftMotorButtonPushed(app, event)
            app.userInput = 'e';
        end

        % Callback function
        function CalRightMotorButtonPushed(app, event)
            app.userInput = 'r';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, 'IMU 3D visualization')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.UIAxesSignal = uiaxes(app.UIFigure);
            title(app.UIAxesSignal, 'Signal')
            xlabel(app.UIAxesSignal, 'X')
            ylabel(app.UIAxesSignal, 'Y')
            zlabel(app.UIAxesSignal, 'Z')
            app.UIAxesZAngle = uiaxes(app.UIFigure);
            title(app.UIAxesZAngle, 'Pendulum Orientation Visualization')
            xlabel(app.UIAxesZAngle, 'X')
            ylabel(app.UIAxesZAngle, 'Y')
            zlabel(app.UIAxesZAngle, 'Z')
            app.Yawangle_xdegEditFieldLabel = uilabel(app.UIFigure);
            app.Yawangle_xdegEditFieldLabel.Text = 'Yaw: angle_x (deg)';
            app.Yawangle_xdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.Pitchangle_ydegEditFieldLabel = uilabel(app.UIFigure);
            app.Pitchangle_ydegEditFieldLabel.Text = 'Pitch: angle_y (deg)';
            app.Pitchangle_ydegEditField = uieditfield(app.UIFigure, 'numeric');
            app.Rollangle_zdegEditFieldLabel = uilabel(app.UIFigure);
            app.Rollangle_zdegEditFieldLabel.Text = 'Roll: angle_z (deg)';
            app.Rollangle_zdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsoleTextAreaLabel = uilabel(app.UIFigure);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.UIFigure);
            app.Pincr = uibutton(app.UIFigure, 'push');
            app.Pincr.ButtonPushedFcn = createCallbackFcn(app, @PincrButtonPushed, true);
            app.Pincr.Text = 'P++';
            app.Pdecr = uibutton(app.UIFigure, 'push');
            app.Pdecr.ButtonPushedFcn = createCallbackFcn(app, @PdecrButtonPushed, true);
            app.Pdecr.Text = 'P--';
            app.StartStopmotorsButton = uibutton(app.UIFigure, 'push');
            app.StartStopmotorsButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopmotorsButtonPushed, true);
            app.StartStopmotorsButton.Text = 'Start/Stop motors';
            app.Iincr = uibutton(app.UIFigure, 'push');
            app.Iincr.ButtonPushedFcn = createCallbackFcn(app, @IincrButtonPushed, true);
            app.Iincr.Text = 'I++';
            app.Idecr = uibutton(app.UIFigure, 'push');
            app.Idecr.ButtonPushedFcn = createCallbackFcn(app, @IdecrButtonPushed, true);
            app.Idecr.Text = 'I--';
            app.Dincr = uibutton(app.UIFigure, 'push');
            app.Dincr.ButtonPushedFcn = createCallbackFcn(app, @DincrButtonPushed, true);
            app.Dincr.Text = 'D++';
            app.Ddecr = uibutton(app.UIFigure, 'push');
            app.Ddecr.ButtonPushedFcn = createCallbackFcn(app, @DdecrButtonPushed, true);
            app.Ddecr.Text = 'D--';
            app.Overlapincr = uibutton(app.UIFigure, 'push');
            app.Overlapincr.ButtonPushedFcn = createCallbackFcn(app, @OverlapincrButtonPushed, true);
            app.Overlapincr.Text = 'Overlap++';
            app.Overlapdecr = uibutton(app.UIFigure, 'push');
            app.Overlapdecr.ButtonPushedFcn = createCallbackFcn(app, @OverlapdecrButtonPushed, true);
            app.Overlapdecr.Text = 'Overlap--';
            app.Min_signalincr = uibutton(app.UIFigure, 'push');
            app.Min_signalincr.ButtonPushedFcn = createCallbackFcn(app, @Min_signalincrButtonPushed, true);
            app.Min_signalincr.Text = 'Min signal++';
            app.Min_signaldecr = uibutton(app.UIFigure, 'push');
            app.Min_signaldecr.ButtonPushedFcn = createCallbackFcn(app, @Min_signaldecrButtonPushed, true);
            app.Min_signaldecr.Text = 'Min signal--';
            app.PIDTunningToolLabel = uilabel(app.UIFigure);
            app.PIDTunningToolLabel.Text = 'PID Tunning Tool';
            app.ControlLabel = uilabel(app.UIFigure);
            app.ControlLabel.Text = 'Control';
            app.CalIMU = uibutton(app.UIFigure, 'push');
            app.CalIMU.ButtonPushedFcn = createCallbackFcn(app, @CalIMUButtonPushed, true);
            app.CalIMU.Text = 'Re-calibrate MPU';
            app.TareLoadCell = uibutton(app.UIFigure, 'push');
            app.TareLoadCell.ButtonPushedFcn = createCallbackFcn(app, @TareLoadCellButtonPushed, true);
            app.TareLoadCell.Text = 'Re-tare load cell';
            app.LoadcellgEditFieldLabel = uilabel(app.UIFigure);
            app.LoadcellgEditFieldLabel.Text = 'Load cell (g)';
            app.LoadcellgEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_designer_3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end